classdef WorkingAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalculatedDImensionsLabel     matlab.ui.control.Label
        ResultsTable                  matlab.ui.control.Table
        GenerateButton                matlab.ui.control.Button
        KneeProsthesisSizingCalculatorLabel  matlab.ui.control.Label
        PatientbodyheightmEditField   matlab.ui.control.NumericEditField
        PatientbodyheightmEditFieldLabel  matlab.ui.control.Label
        PatientbodyweightkgEditField  matlab.ui.control.NumericEditField
        PatientbodyweightkgEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            BW = app.PatientbodyweightkgEditField.Value;
            H = app.PatientbodyheightmEditField.Value;
            rootDir = fileparts(fileparts(which('WorkingAnalysisApp')));
            addpath(rootDir);
            addpath(fullfile(rootDir, 'analysis'));
            addpath(fullfile(rootDir, 'components'));

            resultsTable = Main.getResults(BW,H);
            app.ResultsTable.Data = table2cell(resultsTable);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientbodyweightkgEditFieldLabel = uilabel(app.UIFigure);
            app.PatientbodyweightkgEditFieldLabel.Text = 'Patient body weight (kg)';
            app.PatientbodyweightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientbodyweightkgEditField.Limits = [0 100];
            app.PatientbodyheightmEditFieldLabel = uilabel(app.UIFigure);
            app.PatientbodyheightmEditFieldLabel.Text = 'Patient body height (m)';
            app.PatientbodyheightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientbodyheightmEditField.Limits = [0 2];
            app.KneeProsthesisSizingCalculatorLabel = uilabel(app.UIFigure);
            app.KneeProsthesisSizingCalculatorLabel.Text = 'Knee Prosthesis Sizing Calculator';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Variable name'; 'Description'; 'Value'; 'Units'};
            app.ResultsTable.RowName = {};
            app.CalculatedDImensionsLabel = uilabel(app.UIFigure);
            app.CalculatedDImensionsLabel.Text = 'Calculated DImensions';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end